classdef balanceInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AnimationTab                    matlab.ui.container.Tab
        SimulerButton                   matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        RinitialiserButton              matlab.ui.control.Button
        SimulationdelabalanceLabel      matlab.ui.control.Label
        MasseSlider                     matlab.ui.control.Slider
        MassechoisieEditFieldLabel      matlab.ui.control.Label
        MassechoisieEditField           matlab.ui.control.NumericEditField
        MasseafficheEditFieldLabel      matlab.ui.control.Label
        MasseafficheEditField           matlab.ui.control.NumericEditField
        SauvegarderEditField            matlab.ui.control.EditField
        SauvegarderButton               matlab.ui.control.Button
        CompterButton                   matlab.ui.control.Button
        TarerButton                     matlab.ui.control.Button
        NombredepicesEditFieldLabel     matlab.ui.control.Label
        NombredepicesEditField          matlab.ui.control.NumericEditField
        ParamtrisationTab               matlab.ui.container.Tab
        ParamtresLabel                  matlab.ui.control.Label
        ActionneurLabel                 matlab.ui.control.Label
        ChampmagntiqueEditFieldLabel    matlab.ui.control.Label
        ChampmagntiqueEditField         matlab.ui.control.NumericEditField
        DiamtreEditFieldLabel           matlab.ui.control.Label
        DiamtreEditField                matlab.ui.control.NumericEditField
        NombredetoursEditFieldLabel     matlab.ui.control.Label
        NombredetoursEditField          matlab.ui.control.NumericEditField
        CoefficientdefrottementEditFieldLabel  matlab.ui.control.Label
        CoefficientdefrottementEditField  matlab.ui.control.NumericEditField
        InertieEditFieldLabel           matlab.ui.control.Label
        InertieEditField                matlab.ui.control.NumericEditField
        AmplificateurLabel              matlab.ui.control.Label
        TensiondeoffsetEditFieldLabel   matlab.ui.control.Label
        TensiondeoffsetEditField        matlab.ui.control.NumericEditField
        GaindelampliaudioEditFieldLabel  matlab.ui.control.Label
        GaindelampliaudioEditField      matlab.ui.control.NumericEditField
        ConstantedetempsdelampliaudioEditFieldLabel  matlab.ui.control.Label
        ConstantedetempsdelampliaudioEditField  matlab.ui.control.NumericEditField
        InductancedelabobineEditFieldLabel  matlab.ui.control.Label
        InductancedelabobineEditField   matlab.ui.control.NumericEditField
        RsistancequivalentedepuissanceEditFieldLabel  matlab.ui.control.Label
        RsistancequivalentedepuissanceEditField  matlab.ui.control.NumericEditField
        RsistancedelabobineEditFieldLabel  matlab.ui.control.Label
        RsistancedelabobineEditField    matlab.ui.control.NumericEditField
        ConstantecontrelectromotriceEditFieldLabel  matlab.ui.control.Label
        ConstantecontrelectromotriceEditField  matlab.ui.control.NumericEditField
        GaincouranttensionEditFieldLabel  matlab.ui.control.Label
        GaincouranttensionEditField     matlab.ui.control.NumericEditField
        CourantinitialEditFieldLabel    matlab.ui.control.Label
        CourantinitialEditField         matlab.ui.control.NumericEditField
        CapteurdepositionLabel          matlab.ui.control.Label
        AmplitudedeloscillateurEditFieldLabel  matlab.ui.control.Label
        AmplitudedeloscillateurEditField  matlab.ui.control.NumericEditField
        DistanceentreleslectrodesEditFieldLabel  matlab.ui.control.Label
        DistanceentreleslectrodesEditField  matlab.ui.control.NumericEditField
        Gaindu1erampliEditFieldLabel    matlab.ui.control.Label
        Gaindu1erampliEditField         matlab.ui.control.NumericEditField
        Tensiondcalage1erampliEditFieldLabel  matlab.ui.control.Label
        Tensiondcalage1erampliEditField  matlab.ui.control.NumericEditField
        FrquencedecoupuredufiltreEditFieldLabel  matlab.ui.control.Label
        FrquencedecoupuredufiltreEditField  matlab.ui.control.NumericEditField
        LameLabel                       matlab.ui.control.Label
        fffEditFieldLabel               matlab.ui.control.Label
        fffEditField                    matlab.ui.control.NumericEditField
        gggEditFieldLabel               matlab.ui.control.Label
        gggEditField                    matlab.ui.control.NumericEditField
        hhhEditFieldLabel               matlab.ui.control.Label
        hhhEditField                    matlab.ui.control.NumericEditField
        jjEditFieldLabel                matlab.ui.control.Label
        jjEditField                     matlab.ui.control.NumericEditField
        iiiEditFieldLabel               matlab.ui.control.Label
        iiiEditField                    matlab.ui.control.NumericEditField
        MassechoisiegEditFieldLabel     matlab.ui.control.Label
        MassechoisiegEditField          matlab.ui.control.NumericEditField
        SimulerButton_2                 matlab.ui.control.Button
        ValeurspardfautButton           matlab.ui.control.Button
        MasseaffichegEditFieldLabel     matlab.ui.control.Label
        MasseaffichegEditField          matlab.ui.control.NumericEditField
        Gaindu2eampliEditFieldLabel     matlab.ui.control.Label
        Gaindu2eampliEditField          matlab.ui.control.NumericEditField
        Tensiondedcalagedu2eampliEditFieldLabel  matlab.ui.control.Label
        Tensiondedcalagedu2eampliEditField  matlab.ui.control.NumericEditField
        Rsistance1EditFieldLabel        matlab.ui.control.Label
        Rsistance1EditField             matlab.ui.control.NumericEditField
        paisseurdelalameEditFieldLabel  matlab.ui.control.Label
        paisseurdelalameEditField       matlab.ui.control.NumericEditField
        Rsistance2EditFieldLabel        matlab.ui.control.Label
        Rsistance2EditField             matlab.ui.control.NumericEditField
        Rsistance4EditFieldLabel        matlab.ui.control.Label
        Rsistance4EditField             matlab.ui.control.NumericEditField
        Rsistance3EditFieldLabel        matlab.ui.control.Label
        Rsistance3EditField             matlab.ui.control.NumericEditField
        thEditFieldLabel                matlab.ui.control.Label
        thEditField                     matlab.ui.control.NumericEditField
        t2EditFieldLabel                matlab.ui.control.Label
        t2EditField                     matlab.ui.control.NumericEditField
        t1EditFieldLabel                matlab.ui.control.Label
        t1EditField                     matlab.ui.control.NumericEditField
        PIDLabel                        matlab.ui.control.Label
    end

    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulerButton, SimulerButton_2
        function SimulerButtonPushed(app, event)
            global simout
            assignin("base", "masse", app.MasseSlider.Value)
           
            assignin("base","B",app.ChampmagntiqueEditField.Value)
            assignin("base","D",app.DiamtreEditField.Value)
            assignin("base","N",app.NombredetoursEditField.Value)
            assignin("base","f",app.CoefficientdefrottementEditField.Value)
            assignin("base","I",app.InertieEditField.Value)
            
            assignin("base","Voffset", app.TensiondeoffsetEditField.Value)
            assignin("base", "Kamp", app.GaindelampliaudioEditField.Value)
            assignin("base","to", app.ConstantedetempsdelampliaudioEditField.Value)
            assignin("base","L", app.InductancedelabobineEditField.Value)
            assignin("base","Req", app.RsistancequivalentedepuissanceEditField.Value)
            assignin("base", "R", app.RsistancedelabobineEditField.Value)
            assignin("base","Ke", app.ConstantecontrelectromotriceEditField.Value)
            assignin("base","Kc", app.GaincouranttensionEditField.Value)
            assignin("base","I0", app.CourantinitialEditField.Value)
            
            assignin("base", "aO", app.AmplitudedeloscillateurEditField.Value)
            assignin("base", "dE", app.DistanceentreleslectrodesEditField.Value)
            assignin("base", "eL", app.paisseurdelalameEditField.Value)
            assignin("base", "gA1", app.Gaindu1erampliEditField.Value)
            assignin("base", "Vd1", app.Tensiondcalage1erampliEditField.Value)
            assignin("base", "R1", app.Rsistance1EditField.Value)
            assignin("base", "R2", app.Rsistance2EditField.Value)
            assignin("base", "fc", app.FrquencedecoupuredufiltreEditField.Value)
            assignin("base", "gA2", app.Gaindu2eampliEditField.Value)
            assignin("base", "Vd2", app.Tensiondedcalagedu2eampliEditField.Value)
            assignin("base", "R3", app.Rsistance3EditField.Value)
            assignin("base", "R4", app.Rsistance4EditField.Value)
            
            simout = sim("balanceCAO.slx").balance
            plot(app.UIAxes, simout.Time, simout.Data)
            app.MasseafficheEditField.Value = simout.Data(end)
            app.MasseaffichegEditField.Value = simout.Data(end)
        end

        % Value changing function: MasseSlider
        function MasseSliderValueChanging(app, event)
            changingValue = event.Value;
            app.MassechoisieEditField.Value = changingValue
            app.MassechoisiegEditField.Value = changingValue
        end

        % Value changed function: MassechoisieEditField
        function MassechoisieEditFieldValueChanged(app, event)
            value = app.MassechoisieEditField.Value;
            app.MassechoisiegEditField.Value = value
            app.MasseSlider.Value = value
        end

        % Button pushed function: RinitialiserButton
        function RinitialiserButtonPushed(app, event)
            app.MasseafficheEditField.Value = 0
            cla(app.UIAxes)
            app.SauvegarderEditField.Value = ""
        end

        % Button pushed function: SauvegarderButton
        function SauvegarderButtonPushed(app, event)
            global simout
            save("Saves\" + app.SauvegarderEditField.Value, "simout")
        end

        % Button pushed function: ValeurspardfautButton
        function ValeurspardfautButtonPushed(app, event)
            app.ChampmagntiqueEditField.Value = 0.1128
            app.DiamtreEditField.Value = 0.0406
            app.NombredetoursEditField.Value = 66
            app.CoefficientdefrottementEditField.Value = 0
            app.InertieEditField.Value = 0
            
            app.TensiondeoffsetEditField.Value = 1.063
            app.GaindelampliaudioEditField.Value = 3.97
            app.ConstantedetempsdelampliaudioEditField.Value = 0.00000462
            app.InductancedelabobineEditField.Value = 0.00095
            app.RsistancequivalentedepuissanceEditField.Value = 1.01
            app.RsistancedelabobineEditField.Value = 1.43
            app.ConstantecontrelectromotriceEditField.Value = 1
            app.GaincouranttensionEditField.Value = 3.01
            app.CourantinitialEditField.Value = 1.64
            
            app.AmplitudedeloscillateurEditField.Value = 1
            app.DistanceentreleslectrodesEditField.Value = 1
            app.paisseurdelalameEditField.Value = 1
            app.Gaindu1erampliEditField.Value = 1
            app.Tensiondcalage1erampliEditField.Value = 1
            app.Rsistance1EditField.Value = 1
            app.Rsistance2EditField.Value = 1
            app.FrquencedecoupuredufiltreEditField.Value = 1
            app.Gaindu2eampliEditField.Value = 1
            app.Tensiondedcalagedu2eampliEditField.Value = 1
            app.Rsistance3EditField.Value = 1
            app.Rsistance4EditField.Value = 1
            
            app.t1EditField.Value = 1
            app.t2EditField.Value = 1
            app.thEditField.Value = 1
        end

        % Value changed function: MassechoisiegEditField
        function MassechoisiegEditFieldValueChanged(app, event)
            value = app.MassechoisiegEditField.Value;
            app.MassechoisieEditField.Value = value
            app.MasseSlider.Value = value
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnimationTab = uitab(app.TabGroup);
            app.AnimationTab.Title = 'Animation';
            app.SimulerButton = uibutton(app.AnimationTab, 'push');
            app.SimulerButton.ButtonPushedFcn = createCallbackFcn(app, @SimulerButtonPushed, true);
            app.SimulerButton.Text = 'Simuler';
            app.UIAxes = uiaxes(app.AnimationTab);
            title(app.UIAxes, 'Animation')
            xlabel(app.UIAxes, 'temps (s)')
            ylabel(app.UIAxes, 'masse (g)')
            app.RinitialiserButton = uibutton(app.AnimationTab, 'push');
            app.RinitialiserButton.ButtonPushedFcn = createCallbackFcn(app, @RinitialiserButtonPushed, true);
            app.RinitialiserButton.Text = 'Réinitialiser';
            app.SimulationdelabalanceLabel = uilabel(app.AnimationTab);
            app.SimulationdelabalanceLabel.Text = {'Simulation'; 'de la balance'};
            app.MasseSlider = uislider(app.AnimationTab);
            app.MasseSlider.ValueChangingFcn = createCallbackFcn(app, @MasseSliderValueChanging, true);
            app.MassechoisieEditFieldLabel = uilabel(app.AnimationTab);
            app.MassechoisieEditFieldLabel.Text = 'Masse choisie';
            app.MassechoisieEditField = uieditfield(app.AnimationTab, 'numeric');
            app.MassechoisieEditField.ValueChangedFcn = createCallbackFcn(app, @MassechoisieEditFieldValueChanged, true);
            app.MasseafficheEditFieldLabel = uilabel(app.AnimationTab);
            app.MasseafficheEditFieldLabel.Text = 'Masse affichée';
            app.MasseafficheEditField = uieditfield(app.AnimationTab, 'numeric');
            app.SauvegarderEditField = uieditfield(app.AnimationTab, 'text');
            app.SauvegarderButton = uibutton(app.AnimationTab, 'push');
            app.SauvegarderButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegarderButtonPushed, true);
            app.SauvegarderButton.Text = 'Sauvegarder';
            app.CompterButton = uibutton(app.AnimationTab, 'push');
            app.CompterButton.Text = 'Compter';
            app.TarerButton = uibutton(app.AnimationTab, 'push');
            app.TarerButton.Text = 'Tarer';
            app.NombredepicesEditFieldLabel = uilabel(app.AnimationTab);
            app.NombredepicesEditFieldLabel.Text = 'Nombre de pièces';
            app.NombredepicesEditField = uieditfield(app.AnimationTab, 'numeric');
            app.ParamtrisationTab = uitab(app.TabGroup);
            app.ParamtrisationTab.Title = 'Paramétrisation';
            app.ParamtresLabel = uilabel(app.ParamtrisationTab);
            app.ParamtresLabel.Text = 'Paramètres';
            app.ActionneurLabel = uilabel(app.ParamtrisationTab);
            app.ActionneurLabel.Text = 'Actionneur';
            app.ChampmagntiqueEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.ChampmagntiqueEditFieldLabel.Text = 'Champ magnétique';
            app.ChampmagntiqueEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.ChampmagntiqueEditField.Value = 0.1128;
            app.DiamtreEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.DiamtreEditFieldLabel.Text = 'Diamètre';
            app.DiamtreEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.DiamtreEditField.Value = 0.0406;
            app.NombredetoursEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.NombredetoursEditFieldLabel.Text = 'Nombre de tours';
            app.NombredetoursEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.NombredetoursEditField.Value = 66;
            app.CoefficientdefrottementEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.CoefficientdefrottementEditFieldLabel.Text = 'Coefficient de frottement';
            app.CoefficientdefrottementEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.InertieEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.InertieEditFieldLabel.Text = 'Inertie';
            app.InertieEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.AmplificateurLabel = uilabel(app.ParamtrisationTab);
            app.AmplificateurLabel.Text = 'Amplificateur';
            app.TensiondeoffsetEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.TensiondeoffsetEditFieldLabel.Text = 'Tension de offset';
            app.TensiondeoffsetEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.TensiondeoffsetEditField.Value = 1.063;
            app.GaindelampliaudioEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.GaindelampliaudioEditFieldLabel.Text = 'Gain de l''ampli audio';
            app.GaindelampliaudioEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.GaindelampliaudioEditField.Value = 3.97;
            app.ConstantedetempsdelampliaudioEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.ConstantedetempsdelampliaudioEditFieldLabel.Text = 'Constante de temps de l''ampli audio';
            app.ConstantedetempsdelampliaudioEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.ConstantedetempsdelampliaudioEditField.Value = 4.62e-06;
            app.InductancedelabobineEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.InductancedelabobineEditFieldLabel.Text = 'Inductance de la bobine';
            app.InductancedelabobineEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.InductancedelabobineEditField.Value = 0.00095;
            app.RsistancequivalentedepuissanceEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.RsistancequivalentedepuissanceEditFieldLabel.Text = 'Résistance équivalente de puissance';
            app.RsistancequivalentedepuissanceEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.RsistancequivalentedepuissanceEditField.Value = 1.01;
            app.RsistancedelabobineEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.RsistancedelabobineEditFieldLabel.Text = 'Résistance de la bobine';
            app.RsistancedelabobineEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.RsistancedelabobineEditField.Value = 1.43;
            app.ConstantecontrelectromotriceEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.ConstantecontrelectromotriceEditFieldLabel.Text = 'Constante contre-électromotrice';
            app.ConstantecontrelectromotriceEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.ConstantecontrelectromotriceEditField.Value = 1;
            app.GaincouranttensionEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.GaincouranttensionEditFieldLabel.Text = 'Gain courant-tension';
            app.GaincouranttensionEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.GaincouranttensionEditField.Value = 3.01;
            app.CourantinitialEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.CourantinitialEditFieldLabel.Text = 'Courant initial';
            app.CourantinitialEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.CourantinitialEditField.Value = 1.64;
            app.CapteurdepositionLabel = uilabel(app.ParamtrisationTab);
            app.CapteurdepositionLabel.Text = 'Capteur de position';
            app.AmplitudedeloscillateurEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.AmplitudedeloscillateurEditFieldLabel.Text = 'Amplitude de l''oscillateur';
            app.AmplitudedeloscillateurEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.AmplitudedeloscillateurEditField.Value = 1;
            app.DistanceentreleslectrodesEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.DistanceentreleslectrodesEditFieldLabel.Text = 'Distance entre les électrodes';
            app.DistanceentreleslectrodesEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.DistanceentreleslectrodesEditField.Value = 2;
            app.Gaindu1erampliEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Gaindu1erampliEditFieldLabel.Text = 'Gain du 1er ampli';
            app.Gaindu1erampliEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Gaindu1erampliEditField.Value = 1;
            app.Tensiondcalage1erampliEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Tensiondcalage1erampliEditFieldLabel.Text = 'Tension décalage 1er ampli';
            app.Tensiondcalage1erampliEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Tensiondcalage1erampliEditField.Value = 1;
            app.FrquencedecoupuredufiltreEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.FrquencedecoupuredufiltreEditFieldLabel.Text = 'Fréquence de coupure du filtre';
            app.FrquencedecoupuredufiltreEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.FrquencedecoupuredufiltreEditField.Value = 1;
            app.LameLabel = uilabel(app.ParamtrisationTab);
            app.LameLabel.Text = 'Lame';
            app.fffEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.fffEditFieldLabel.Text = 'fff';
            app.fffEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.gggEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.gggEditFieldLabel.Text = 'ggg';
            app.gggEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.hhhEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.hhhEditFieldLabel.Text = 'hhh';
            app.hhhEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.jjEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.jjEditFieldLabel.Text = 'jj';
            app.jjEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.iiiEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.iiiEditFieldLabel.Text = 'iii';
            app.iiiEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.MassechoisiegEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.MassechoisiegEditFieldLabel.Text = 'Masse choisie (g)';
            app.MassechoisiegEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.MassechoisiegEditField.ValueChangedFcn = createCallbackFcn(app, @MassechoisiegEditFieldValueChanged, true);
            app.SimulerButton_2 = uibutton(app.ParamtrisationTab, 'push');
            app.SimulerButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimulerButtonPushed, true);
            app.SimulerButton_2.Text = 'Simuler';
            app.ValeurspardfautButton = uibutton(app.ParamtrisationTab, 'push');
            app.ValeurspardfautButton.ButtonPushedFcn = createCallbackFcn(app, @ValeurspardfautButtonPushed, true);
            app.ValeurspardfautButton.Text = 'Valeurs par défaut';
            app.MasseaffichegEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.MasseaffichegEditFieldLabel.Text = 'Masse affichée (g)';
            app.MasseaffichegEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Gaindu2eampliEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Gaindu2eampliEditFieldLabel.Text = 'Gain du 2e ampli';
            app.Gaindu2eampliEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Gaindu2eampliEditField.Value = 1;
            app.Tensiondedcalagedu2eampliEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Tensiondedcalagedu2eampliEditFieldLabel.Text = 'Tension de décalage du 2e ampli';
            app.Tensiondedcalagedu2eampliEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Tensiondedcalagedu2eampliEditField.Value = 1;
            app.Rsistance1EditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Rsistance1EditFieldLabel.Text = 'Résistance 1';
            app.Rsistance1EditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Rsistance1EditField.Value = 1;
            app.paisseurdelalameEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.paisseurdelalameEditFieldLabel.Text = 'Épaisseur de la lame';
            app.paisseurdelalameEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.paisseurdelalameEditField.Value = 1;
            app.Rsistance2EditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Rsistance2EditFieldLabel.Text = 'Résistance 2';
            app.Rsistance2EditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Rsistance2EditField.Value = 1;
            app.Rsistance4EditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Rsistance4EditFieldLabel.Text = 'Résistance 4';
            app.Rsistance4EditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Rsistance4EditField.Value = 1;
            app.Rsistance3EditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Rsistance3EditFieldLabel.Text = 'Résistance 3';
            app.Rsistance3EditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Rsistance3EditField.Value = 1;
            app.thEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.thEditFieldLabel.Text = 'th';
            app.thEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.thEditField.Value = 1;
            app.t2EditFieldLabel = uilabel(app.ParamtrisationTab);
            app.t2EditFieldLabel.Text = 't2';
            app.t2EditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.t2EditField.Value = 1;
            app.t1EditFieldLabel = uilabel(app.ParamtrisationTab);
            app.t1EditFieldLabel.Text = 't1';
            app.t1EditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.t1EditField.Value = 1;
            app.PIDLabel = uilabel(app.ParamtrisationTab);
            app.PIDLabel.Text = 'PID';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = balanceInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end